classdef ORingDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MaterialSelctionTab            matlab.ui.container.Tab
        MaximumWorkingPressureEditFieldLabel  matlab.ui.control.Label
        MaximumWorkingPressureEditField  matlab.ui.control.NumericEditField
        MaxWorkingTemperatureCEditFieldLabel  matlab.ui.control.Label
        MaxWorkingTemperatureCEditField  matlab.ui.control.NumericEditField
        MinWorkingTemperatureCEditFieldLabel  matlab.ui.control.Label
        MinWorkingTemperatureCEditField  matlab.ui.control.NumericEditField
        HardnessReccomendedLabel       matlab.ui.control.Label
        Hardness                       matlab.ui.control.Label
        BackUpRingReccomendedLabel     matlab.ui.control.Label
        BackupRecc                     matlab.ui.control.Label
        MaximumDiametralClearancemmEditField_2Label  matlab.ui.control.Label
        MaximumDiametralClearancemmEditField_2  matlab.ui.control.NumericEditField
        CalculateButton_2              matlab.ui.control.Button
        SelectedHardnessDropDownLabel  matlab.ui.control.Label
        SelectedHardnessDropDown       matlab.ui.control.DropDown
        Durometer70EditFieldLabel      matlab.ui.control.Label
        Durometer70EditField           matlab.ui.control.NumericEditField
        Durometer80EditFieldLabel      matlab.ui.control.Label
        Durometer80EditField           matlab.ui.control.NumericEditField
        Durometer90EditFieldLabel      matlab.ui.control.Label
        Durometer90EditField           matlab.ui.control.NumericEditField
        ReccomendedMaterialsLabel      matlab.ui.control.Label
        UITable3                       matlab.ui.control.Table
        UITable3_2                     matlab.ui.control.Table
        SizeSelectionTab               matlab.ui.container.Tab
        BoreDiametermmEditFieldLabel   matlab.ui.control.Label
        BoreDiametermmEditField        matlab.ui.control.NumericEditField
        MaximumDiametralClearancemmLabel  matlab.ui.control.Label
        MaximumDiametralClearancemmEditField  matlab.ui.control.NumericEditField
        UITable2                       matlab.ui.control.Table
        GrooveDimensionsDropDownLabel  matlab.ui.control.Label
        GrooveDimensionsDropDown       matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        WidthLabel                     matlab.ui.control.Label
        DepthLabel_2                   matlab.ui.control.Label
        DepthLabel_3                   matlab.ui.control.Label
        WidthLabel_2                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        SizeData %Size chart for standard AS589 O-rings
        DiaClear % Diametral Clearance
        PotentialRings %Final Dimensions
        Pressure %Internal Pressure
        d70
        d80
        d90
        MatData
        Names
        
    end
    
    methods (Access = private)
        
        function PotentialRings = SizeSelect(app,BoreDia, DiaClear, SizeData)
            
            CompGoal = 0.2;
            CompMin = 0.05;
            CompMax = 0.3;
            %SizeData = xlsread('OringSizeChart.xlsx');
            
            dim = size(SizeData);
            Q = 0;
            AccSize = [];
            for i = 1:dim(1)
                CS = SizeData(i,5);
                CStol = SizeData(i,8);
                CSMax = CStol+CS;
                CSMin = CS-CStol;
                HeightGoal = (1-CompGoal)*CS;
                HeightMax = CSMin*(1-CompMin);
                HeightMin = CSMax*(1-CompMax);
                
                
                OVol = pi*(CS/2)^2;
                GlandFillGoal = 0.70;
                %GlandFillMax = 0.8;
                %GlandFillMin = 0.6;
                
                GlandArea = OVol/GlandFillGoal;
                %GlandAreaMin = OVol/GlandFillMin;
                %GlandAreaMax = OVol/GlandFillMax;
                
                %GlandWidth = GlandArea/HeightGoal;
                ID = 1.10*SizeData(i,6); %ID should be 10% smaller than Groove Diameter
                HeightNeed = 0.5*(-ID + BoreDia)-0.5*DiaClear;
                
                if HeightNeed > HeightMax || HeightNeed < HeightMin
                    
                else
                    FinalWidth = GlandArea/(HeightNeed);%-DiaClear);
                    FinalDepth = HeightNeed;
                    Diff = abs(HeightNeed-HeightGoal);
                    Q = Q+1;
                    AccSize(Q,1) = SizeData(i,1);
                    AccSize(Q,2) = FinalDepth;
                    AccSize(Q,3) = FinalWidth;
                    AccSize(Q,4) = Diff;


                end
                
            end
            PotentialRings = sortrows(AccSize,4);
            %PotentialRings(:,4) = [];
        end
        
        function [Acceptable,Final] = Dataclean(app,test, Norm,Names)
            k = length(Names);
            for i = 1:k
                Final(i,1) = i;
                if test(1) > Norm(i,1)
                    Final(i,2) = 1;
                else
                    Final(i,2) = 0;
                end
                
                if test(2) < Norm(i,2)
                    Final(i,3) = 1;
                else
                    Final(i,3) = 0;
                end
            end
            j = 0;
            for i = 1:k
                if Final(i,2) == 1 && Final(i,3) == 1
                    j = j+1;
                    Acceptable(j) = Names(i);
                end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SizeData = xlsread('OringSizeChart.xlsx');
            app.MatData = readtable('MaterialDat.xlsx');
            app.Names = table2array(app.MatData(:,1));
 
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.GrooveDimensionsDropDown.Enable = true;
            %app.Pressure = app.MaximumWorkingPressureEditField.Value;
            app.DiaClear = app.MaximumDiametralClearancemmEditField.Value;
            app.PotentialRings = SizeSelect(app, app.BoreDiametermmEditField.Value, app.DiaClear, app.SizeData);
            app.UITable2.Data = app.PotentialRings;
            k = size(app.PotentialRings);
            k = k(1);
            for i = 1:k
                Data{i} = num2str(app.PotentialRings(i,1));
            end
            app.GrooveDimensionsDropDown.Items = Data;
            GrooveDims = app.PotentialRings(:,1);
            app.GrooveDimensionsDropDown.ItemsData = GrooveDims;
            app.DepthLabel_2.Text = num2str(app.PotentialRings(1,2))+" mm";
            app.WidthLabel.Text = num2str(app.PotentialRings(1,3))+" mm";
            
            
        end

        % Value changed function: GrooveDimensionsDropDown
        function GrooveDimensionsDropDownValueChanged(app, event)
            value = app.GrooveDimensionsDropDown.Value;
            k = size(app.PotentialRings);
            k = k(1);
            for i = 1:k
                if value == app.PotentialRings(i,1)
                    app.DepthLabel_2.Text = num2str(app.PotentialRings(i,2))+" mm";
                    app.WidthLabel.Text = num2str(app.PotentialRings(i,3))+" mm";
                end
            end
            
            
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            duro90 = @(x) -1.77231152643309E-19*x^5 + 4.97415425012759E-15*x^4 - 5.24290673872112E-11*x^3 + 2.62406870815886E-07*x^2 - 6.76829743035854E-04*x + 9.37840083840230E-01;
            
            
            duro80 = @(x) -1.07354072495579E-18*x^5 + 1.98812444923105E-14*x^4 - 1.43113845022138E-10*x^3 + 5.12915917281170E-07*x^2 - 9.85289693403442E-04*x + 9.41002034016699E-01;
            
            
            duro70 = @(x) -4.50460267520007E-14*x^4 + 2.59430015359079E-10*x^3 - 3.15409866950029E-07*x^2 - 4.79041602725853E-04*x + 9.20302847838529E-01;
            
            app.Pressure = app.MaximumWorkingPressureEditField.Value;
            app.Durometer70EditField.Value = duro70(app.Pressure);
            app.Durometer80EditField.Value = duro80(app.Pressure);
            app.Durometer90EditField.Value = duro90(app.Pressure);
            app.d70 = duro70(app.Pressure);
            app.d80 = duro80(app.Pressure);
            app.d90 = duro90(app.Pressure);
            app.SelectedHardnessDropDown.Enable = true;
            app.SelectedHardnessDropDown.ItemsData = [app.d70, app.d80, app.d90];
            app.BackupRecc.Text = "N/A";
            
            if app.Pressure < 1500
                app.Hardness.Text = "Duro 70"
            elseif app.Pressure > 3000
                app.Hardness.Text = "Duro 90"
                app.BackupRecc.Text = "Probably"
            else
                app.Hardness.Text = "Duro 80"
            end
            
            if app.Pressure > 3000
                app.Durometer70EditField.Value = 0;
            elseif app.Pressure > 6000
                app.Durometer80EditField.Value = 0;
                app.Durometer70EditField.Value = 0;
            end
            Norm = app.MatData;
            Norm(:,5) = [];
            Norm(:,4) = [];
            Norm(:,1) = [];
            Norm = table2array(Norm);
            
            Ex = app.MatData;
            Ex(:,3) = [];
            Ex(:,2) = [];
            Ex(:,1) = [];
            Ex = table2array(Ex);
            
            test = [app.MinWorkingTemperatureCEditField.Value, app.MaxWorkingTemperatureCEditField.Value];
            [Accept,FinalN] = app.Dataclean(test, Norm, app.Names);
            [~,FinalS] = app.Dataclean(test, Ex, app.Names);
            
            %             for i = 1:length(ShortDurAccept)
            %                 for j = 1:length(Accept)
            %                     if ShortDurAccept{i} == Accept{j}
            %                         Isgood{i} = 0;
            %                         break
            %                     else
            %                         Isgood{i} = 1;
            %                     end
            %                 end
            %             end
            %             m = 1;
            %             for i = 1:length(ShortDurAccept)
            %                 if Isgood{i} == 1
            %                     ShortAcc{m} = ShortDurAccept{i};
            %                     m = m+1;
            %                 end
            %             end
            
            k = length(app.Names);
            
            for i = 1:k
                if FinalN(i,2) == 1 && FinalN(i,3) == 1
                    TestN(i) = 1;
                end
            end
            
            
            for i = 1:k
                if FinalS(i,2) == 1 && FinalS(i,3) == 1
                    TestS(i) = 1;
                end
            end
            
            Test = TestN-TestS;
            ShortAcc = {};
            j = 1;
            for i = 1:k
                if Test(i) == -1
                    ShortAcc(j) = app.Names(i);
                    j = j+1;
                end
            end


            app.UITable3.Data = Accept';
            if length(ShortAcc) > 1
                app.UITable3_2.Data = ShortAcc';
            else 
                app.UITable3_2.Data = [];
            end


        end

        % Value changed function: SelectedHardnessDropDown
        function SelectedHardnessDropDownValueChanged(app, event)
            Duro = app.SelectedHardnessDropDown.Value;
            app.MaximumDiametralClearancemmEditField_2.Value = Duro;
            app.MaximumDiametralClearancemmEditField.Value = Duro;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MaterialSelctionTab = uitab(app.TabGroup);
            app.MaterialSelctionTab.Title = 'Material Selction';
            app.MaximumWorkingPressureEditFieldLabel = uilabel(app.MaterialSelctionTab);
            app.MaximumWorkingPressureEditFieldLabel.Text = 'Maximum Working Pressure';
            app.MaximumWorkingPressureEditField = uieditfield(app.MaterialSelctionTab, 'numeric');
            app.MaximumWorkingPressureEditField.Value = 5000;
            app.MaxWorkingTemperatureCEditFieldLabel = uilabel(app.MaterialSelctionTab);
            app.MaxWorkingTemperatureCEditFieldLabel.Text = 'Max Working Temperature (C)';
            app.MaxWorkingTemperatureCEditField = uieditfield(app.MaterialSelctionTab, 'numeric');
            app.MaxWorkingTemperatureCEditField.Value = 160;
            app.MinWorkingTemperatureCEditFieldLabel = uilabel(app.MaterialSelctionTab);
            app.MinWorkingTemperatureCEditFieldLabel.Text = 'Min Working Temperature (C)';
            app.MinWorkingTemperatureCEditField = uieditfield(app.MaterialSelctionTab, 'numeric');
            app.MinWorkingTemperatureCEditField.Value = -20;
            app.HardnessReccomendedLabel = uilabel(app.MaterialSelctionTab);
            app.HardnessReccomendedLabel.Text = 'Hardness Reccomended';
            app.Hardness = uilabel(app.MaterialSelctionTab);
            app.Hardness.Text = 'N/A';
            app.BackUpRingReccomendedLabel = uilabel(app.MaterialSelctionTab);
            app.BackUpRingReccomendedLabel.Text = 'Back-Up Ring Reccomended?';
            app.BackupRecc = uilabel(app.MaterialSelctionTab);
            app.BackupRecc.Text = 'N/A';
            app.MaximumDiametralClearancemmEditField_2Label = uilabel(app.MaterialSelctionTab);
            app.MaximumDiametralClearancemmEditField_2Label.Text = 'Maximum Diametral Clearance (mm)* ';
            app.MaximumDiametralClearancemmEditField_2 = uieditfield(app.MaterialSelctionTab, 'numeric');
            app.MaximumDiametralClearancemmEditField_2.Editable = 'off';
            app.CalculateButton_2 = uibutton(app.MaterialSelctionTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.SelectedHardnessDropDownLabel = uilabel(app.MaterialSelctionTab);
            app.SelectedHardnessDropDownLabel.Enable = 'off';
            app.SelectedHardnessDropDownLabel.Text = 'Selected Hardness';
            app.SelectedHardnessDropDown = uidropdown(app.MaterialSelctionTab);
            app.SelectedHardnessDropDown.Items = {'Durometer 70', ' Durometer 80', ' Durometer 90'};
            app.SelectedHardnessDropDown.ItemsData = {'70', ' 80', ' 90'};
            app.SelectedHardnessDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedHardnessDropDownValueChanged, true);
            app.SelectedHardnessDropDown.Enable = 'off';
            app.SelectedHardnessDropDown.Value = ' 80';
            app.Durometer70EditFieldLabel = uilabel(app.MaterialSelctionTab);
            app.Durometer70EditFieldLabel.Text = 'Durometer 70';
            app.Durometer70EditField = uieditfield(app.MaterialSelctionTab, 'numeric');
            app.Durometer80EditFieldLabel = uilabel(app.MaterialSelctionTab);
            app.Durometer80EditFieldLabel.Text = 'Durometer 80';
            app.Durometer80EditField = uieditfield(app.MaterialSelctionTab, 'numeric');
            app.Durometer90EditFieldLabel = uilabel(app.MaterialSelctionTab);
            app.Durometer90EditFieldLabel.Text = 'Durometer 90';
            app.Durometer90EditField = uieditfield(app.MaterialSelctionTab, 'numeric');
            app.ReccomendedMaterialsLabel = uilabel(app.MaterialSelctionTab);
            app.ReccomendedMaterialsLabel.Text = 'Reccomended Materials ';
            app.UITable3 = uitable(app.MaterialSelctionTab);
            app.UITable3.ColumnName = {'Acceptable'};
            app.UITable3.RowName = {};
            app.UITable3_2 = uitable(app.MaterialSelctionTab);
            app.UITable3_2.ColumnName = {'Acceptable for Short Duration'};
            app.UITable3_2.RowName = {};
            app.SizeSelectionTab = uitab(app.TabGroup);
            app.SizeSelectionTab.Title = 'Size Selection';
            app.BoreDiametermmEditFieldLabel = uilabel(app.SizeSelectionTab);
            app.BoreDiametermmEditFieldLabel.Text = 'Bore Diameter (mm)';
            app.BoreDiametermmEditField = uieditfield(app.SizeSelectionTab, 'numeric');
            app.BoreDiametermmEditField.Value = 8.5;
            app.MaximumDiametralClearancemmLabel = uilabel(app.SizeSelectionTab);
            app.MaximumDiametralClearancemmLabel.Text = 'Maximum Diametral Clearance (mm)* ';
            app.MaximumDiametralClearancemmEditField = uieditfield(app.SizeSelectionTab, 'numeric');
            app.UITable2 = uitable(app.SizeSelectionTab);
            app.UITable2.ColumnName = {'Dash Code (AS598)'; 'Depth (mm)'; 'Width (mm)'};
            app.UITable2.RowName = {};
            app.GrooveDimensionsDropDownLabel = uilabel(app.SizeSelectionTab);
            app.GrooveDimensionsDropDownLabel.Enable = 'off';
            app.GrooveDimensionsDropDownLabel.Text = 'Groove Dimensions';
            app.GrooveDimensionsDropDown = uidropdown(app.SizeSelectionTab);
            app.GrooveDimensionsDropDown.Items = {};
            app.GrooveDimensionsDropDown.ValueChangedFcn = createCallbackFcn(app, @GrooveDimensionsDropDownValueChanged, true);
            app.GrooveDimensionsDropDown.Enable = 'off';
            app.GrooveDimensionsDropDown.Value = {};
            app.Label = uilabel(app.SizeSelectionTab);
            app.Label.Text = '*: Diametral Clearance is calulated from a polynomial fit to pressure, consult ?image? and change if necessary';
            app.CalculateButton = uibutton(app.SizeSelectionTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.WidthLabel = uilabel(app.SizeSelectionTab);
            app.WidthLabel.Text = 'Width';
            app.DepthLabel_2 = uilabel(app.SizeSelectionTab);
            app.DepthLabel_2.Text = 'Depth';
            app.DepthLabel_3 = uilabel(app.SizeSelectionTab);
            app.DepthLabel_3.Text = 'Depth';
            app.WidthLabel_2 = uilabel(app.SizeSelectionTab);
            app.WidthLabel_2.Text = 'Width';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ORingDesigner

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end